classdef AnyResearchApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RootGrid                       matlab.ui.container.GridLayout
        StatusLabel                    matlab.ui.control.Label
        MainTabGroup                   matlab.ui.container.TabGroup
        SearchTab                      matlab.ui.container.Tab
        SearchGrid                     matlab.ui.container.GridLayout
        SearchRunButton                matlab.ui.control.Button
        SearchParametersPanel          matlab.ui.container.Panel
        SearchParametersGrid           matlab.ui.container.GridLayout
        AdvancedPanel                  matlab.ui.container.Panel
        AdvancedGrid                   matlab.ui.container.GridLayout
        InstitutionIdEditField         matlab.ui.control.EditField
        InstitutionIdLabel             matlab.ui.control.Label
        InstitutionEditField           matlab.ui.control.EditField
        InstitutionLabel               matlab.ui.control.Label
        TopNField                      matlab.ui.control.NumericEditField
        TopNLabel                      matlab.ui.control.Label
        SnowballModeDropDown           matlab.ui.control.DropDown
        SnowballModeLabel              matlab.ui.control.Label
        SeedIdEditField                matlab.ui.control.EditField
        SeedIdLabel                    matlab.ui.control.Label
        FiltersPanel                   matlab.ui.container.Panel
        FiltersGrid                    matlab.ui.container.GridLayout
        RequireAbstractCheckBox        matlab.ui.control.CheckBox
        RequireOpenAccessCheckBox      matlab.ui.control.CheckBox
        CountryCodeEditField           matlab.ui.control.EditField
        LanguageEditField              matlab.ui.control.EditField
        CountryCodeLabel               matlab.ui.control.Label
        LanguageLabel                  matlab.ui.control.Label
        CitedByMaxField                matlab.ui.control.NumericEditField
        CitedByMinField                matlab.ui.control.NumericEditField
        CitedByMaxLabel                matlab.ui.control.Label
        CitedByMinLabel                matlab.ui.control.Label
        FilterTypeEditField            matlab.ui.control.EditField
        FilterTypeLabel                matlab.ui.control.Label
        BasicPanel                     matlab.ui.container.Panel
        BasicGrid                      matlab.ui.container.GridLayout
        SortByDropDown                 matlab.ui.control.DropDown
        SortByLabel                    matlab.ui.control.Label
        ToDatePicker                   matlab.ui.control.DatePicker
        FromDatePicker                 matlab.ui.control.DatePicker
        ToDateLabel                    matlab.ui.control.Label
        FromDateLabel                  matlab.ui.control.Label
        QueryEditField                 matlab.ui.control.EditField
        QueryLabel                     matlab.ui.control.Label
        BatchTab                       matlab.ui.container.Tab
        BatchGrid                      matlab.ui.container.GridLayout
        BatchBackButton                matlab.ui.control.Button
        BatchContentGrid               matlab.ui.container.GridLayout
        BatchRunPanel                  matlab.ui.container.Panel
        BatchRunGrid                   matlab.ui.container.GridLayout
        RunBatchButton                 matlab.ui.control.Button
        BatchDryRunCheckBox            matlab.ui.control.CheckBox
        BatchToDatePicker              matlab.ui.control.DatePicker
        BatchFromDatePicker            matlab.ui.control.DatePicker
        BatchToDateLabel               matlab.ui.control.Label
        BatchFromDateLabel             matlab.ui.control.Label
        BatchQueryEditField            matlab.ui.control.EditField
        BatchQueryLabel                matlab.ui.control.Label
        BatchPromotePanel              matlab.ui.container.Panel
        BatchPromoteGrid               matlab.ui.container.GridLayout
        PromoteCandidatesButton        matlab.ui.control.Button
        PromoteHelpLabel               matlab.ui.control.Label
        BatchReviewPanel               matlab.ui.container.Panel
        BatchReviewGrid                matlab.ui.container.GridLayout
        ReviewCandidatesButton         matlab.ui.control.Button
        CandidateTable                 matlab.ui.control.Table
        BatchGeneratePanel             matlab.ui.container.Panel
        BatchGenerateGrid              matlab.ui.container.GridLayout
        GenerateCandidatesButton       matlab.ui.control.Button
        PrepareCountryEditField        matlab.ui.control.EditField
        PrepareCountryLabel            matlab.ui.control.Label
        TargetNamesTextArea            matlab.ui.control.TextArea
        TargetNamesLabel               matlab.ui.control.Label
        BatchStepLabel                 matlab.ui.control.Label
        AnalyticsPdfTab                matlab.ui.container.Tab
        AnalyticsPdfGrid               matlab.ui.container.GridLayout
        PdfPanel                       matlab.ui.container.Panel
        PdfOptionsGrid                 matlab.ui.container.GridLayout
        EnableKeywordEvidenceCheckBox  matlab.ui.control.CheckBox
        EnablePdfTextCheckBox          matlab.ui.control.CheckBox
        PdfMaxRowsField                matlab.ui.control.NumericEditField
        PdfMaxRowsLabel                matlab.ui.control.Label
        EnablePdfDownloadCheckBox      matlab.ui.control.CheckBox
        AnalyticsPanel                 matlab.ui.container.Panel
        AnalyticsStatusLabel           matlab.ui.control.Label
        SettingsTab                    matlab.ui.container.Tab
        SettingsGrid                   matlab.ui.container.GridLayout
        SettingsHelpLabel              matlab.ui.control.Label
        SaveApiKeyButton               matlab.ui.control.Button
        ApiKeyEditField                matlab.ui.control.EditField
        ApiKeyLabel                    matlab.ui.control.Label
        AppTitleLabel                  matlab.ui.control.Label
    end

    properties (Access = private)
        BatchCandidatePath = "data/list/institutions_candidate.csv"
        BatchStep = 1
        ReviewedInstitutionsPath = "data/list/institutions.csv"
        SettingsPathOverride
    end

    methods (Access = private)

        function options = getBatchPrepareOptions(app)
            names = string(app.TargetNamesTextArea.Value);
            options = struct('targetNames', names, 'countryFilter', strtrim(string(app.PrepareCountryEditField.Value)), ...
                'candidatePath', app.BatchCandidatePath, 'reviewedPath', app.ReviewedInstitutionsPath);
        end

        function options = getBatchRunOptions(app)
            if strlength(strtrim(string(app.BatchQueryEditField.Value))) == 0
                error('AnyResearch:emptyBatchQuery', 'Enter a search query before running the batch.');
            end
            AnyResearchApp.assertDateRange(app.BatchFromDatePicker.Value, app.BatchToDatePicker.Value);
            fromDate = ""; if ~isnat(app.BatchFromDatePicker.Value); fromDate = string(app.BatchFromDatePicker.Value, "yyyy-MM-dd"); end
            toDate = ""; if ~isnat(app.BatchToDatePicker.Value); toDate = string(app.BatchToDatePicker.Value, "yyyy-MM-dd"); end
            options = struct('institutionsCsv', app.ReviewedInstitutionsPath, 'query', strtrim(string(app.BatchQueryEditField.Value)), ...
                'fromDate', fromDate, ...
                'toDate', toDate, ...
                'batchRootDir', string(fullfile(AnyResearchApp.resolveProjectRoot(), 'result', 'batch')), 'dryRun', logical(app.BatchDryRunCheckBox.Value));
        end

        function associateFieldLabels(app)
            % Associate each input with its visible label so screen readers announce the
            % label text. The plain-text XML loader cannot resolve Label references
            % (R2026b), so the association is made after the components exist.
            pairs = {'QueryEditField', 'QueryLabel'; 'FromDatePicker', 'FromDateLabel'; ...
                'ToDatePicker', 'ToDateLabel'; 'SortByDropDown', 'SortByLabel'; ...
                'FilterTypeEditField', 'FilterTypeLabel'; 'CitedByMinField', 'CitedByMinLabel'; ...
                'CitedByMaxField', 'CitedByMaxLabel'; 'LanguageEditField', 'LanguageLabel'; ...
                'CountryCodeEditField', 'CountryCodeLabel'; 'SeedIdEditField', 'SeedIdLabel'; ...
                'SnowballModeDropDown', 'SnowballModeLabel'; 'TopNField', 'TopNLabel'; ...
                'InstitutionEditField', 'InstitutionLabel'; 'InstitutionIdEditField', 'InstitutionIdLabel'; ...
                'TargetNamesTextArea', 'TargetNamesLabel'; 'PrepareCountryEditField', 'PrepareCountryLabel'; ...
                'BatchQueryEditField', 'BatchQueryLabel'; 'BatchFromDatePicker', 'BatchFromDateLabel'; ...
                'BatchToDatePicker', 'BatchToDateLabel'; 'PdfMaxRowsField', 'PdfMaxRowsLabel'; ...
                'ApiKeyEditField', 'ApiKeyLabel'};
            for index = 1:size(pairs, 1)
                app.(pairs{index, 1}).Label = app.(pairs{index, 2});
            end
        end

        function tf = hasSearchQuery(app)
            % run_pipeline accepts an empty query when a seed ID starts a snowball search.
            tf = strlength(strtrim(string(app.QueryEditField.Value))) > 0 ...
                || strlength(strtrim(string(app.SeedIdEditField.Value))) > 0;
        end

        function searchOptions = getSearchOptions(app)
            AnyResearchApp.assertDateRange(app.FromDatePicker.Value, app.ToDatePicker.Value);
            if app.CitedByMaxField.Value > 0 && app.CitedByMinField.Value > app.CitedByMaxField.Value
                error('AnyResearch:invalidCitationRange', ...
                    'Minimum citations must not exceed maximum citations (set the maximum to 0 for no limit).');
            end
            fromDate = ""; if ~isnat(app.FromDatePicker.Value); fromDate = string(app.FromDatePicker.Value, "yyyy-MM-dd"); end
            toDate = ""; if ~isnat(app.ToDatePicker.Value); toDate = string(app.ToDatePicker.Value, "yyyy-MM-dd"); end
            searchOptions = struct('query', strtrim(string(app.QueryEditField.Value)), 'fromDate', fromDate, 'toDate', toDate, ...
                'language', strtrim(string(app.LanguageEditField.Value)), 'requireOpenAccess', logical(app.RequireOpenAccessCheckBox.Value), ...
                'requireAbstract', logical(app.RequireAbstractCheckBox.Value), 'filterCountryCode', strtrim(string(app.CountryCodeEditField.Value)), ...
                'sortBy', string(app.SortByDropDown.Value), 'filterType', strtrim(string(app.FilterTypeEditField.Value)), ...
                'citedByMin', double(app.CitedByMinField.Value), 'citedByMax', double(app.CitedByMaxField.Value), ...
                'seedId', strtrim(string(app.SeedIdEditField.Value)), 'snowballMode', string(app.SnowballModeDropDown.Value), ...
                'topN', double(app.TopNField.Value), 'firstAuthorInstitution', strtrim(string(app.InstitutionEditField.Value)), ...
                'firstAuthorInstitutionId', strtrim(string(app.InstitutionIdEditField.Value)), ...
                'enablePdfDownload', logical(app.EnablePdfDownloadCheckBox.Value), 'pdfMaxRows', double(app.PdfMaxRowsField.Value), ...
                'enablePdfTextExtraction', logical(app.EnablePdfTextCheckBox.Value), ...
                'enableKeywordEvidence', logical(app.EnableKeywordEvidenceCheckBox.Value));
        end

        function loadCandidateReview(app)
            % The candidate CSV can carry columns the review table does not
            % show (works_count), and uitable rejects string cells, so select
            % the displayed columns by name and convert text to char.
            candidateTable = AnyResearchApp.readCandidateCsv(app.BatchCandidatePath);
            displayedNames = string(app.CandidateTable.ColumnName(:))';
            missingNames = setdiff(displayedNames, string(candidateTable.Properties.VariableNames));
            if ~isempty(missingNames)
                error('AnyResearch:candidateColumns', ...
                    'The candidate CSV is missing column(s): %s', strjoin(missingNames, ', '));
            end
            displayed = candidateTable(:, cellstr(displayedNames));
            cells = cell(height(displayed), width(displayed));
            for column = 1:width(displayed)
                values = displayed.(column);
                for row = 1:height(displayed)
                    if displayedNames(column) == "include"
                        cells{row, column} = AnyResearchApp.includeValue(values(row));
                    else
                        cells{row, column} = char(values(row));
                    end
                end
            end
            app.CandidateTable.Data = cells;
        end

        function onBatchRunComplete(app, result, err)
            if ~isempty(err)
                app.StatusLabel.Text = 'Batch failed.';
                uialert(app.UIFigure, err.message, 'Batch failed');
                return;
            end
            if result.dry_run
                app.StatusLabel.Text = sprintf('Batch dry run complete: %d/%d institutions previewed (no works fetched). Output: %s', result.dry_run_count, result.total_institutions, result.batch_dir);
                return;
            end
            app.StatusLabel.Text = sprintf('Batch complete: %d/%d institutions succeeded. Output: %s', result.success_count, result.total_institutions, result.batch_dir);
        end

        function onPrepareInstitutionsComplete(app, result, err)
            if ~isempty(err)
                app.StatusLabel.Text = 'Candidate generation failed.';
                uialert(app.UIFigure, err.message, 'Candidate generation failed');
                return;
            end
            app.BatchCandidatePath = string(result);
            app.loadCandidateReview();
            app.StatusLabel.Text = 'Candidates generated. Review the include, role, and note columns.';
            app.showBatchStep(2);
        end

        function onSearchComplete(app, result, err)
            if ~isempty(err)
                app.StatusLabel.Text = 'Search failed.';
                uialert(app.UIFigure, err.message, 'Search failed');
                return;
            end
            app.StatusLabel.Text = sprintf('Search complete: %d works. Output: %s', result.rows_fetched, result.run_dir);
        end

        function settingsPath = resolveSettingsPath(app)
            if strlength(string(app.SettingsPathOverride)) > 0
                settingsPath = string(app.SettingsPathOverride);
                return;
            end
            projectRoot = AnyResearchApp.resolveProjectRoot();
            settingsPath = fullfile(projectRoot, 'config', 'settings.json');
        end

        function saveApiKey(app)
            apiKey = strtrim(string(app.ApiKeyEditField.Value));
            if strlength(apiKey) == 0
                uialert(app.UIFigure, 'Enter an API key before saving.', 'API key required');
                return;
            end
            try
                settingsPath = app.resolveSettingsPath();
                if isfile(settingsPath); settings = jsondecode(fileread(settingsPath)); else; settings = struct(); end
                if ~isfield(settings, 'openalex'); settings.openalex = struct(); end
                settings.openalex.api_key = char(apiKey);
                settingsJson = string(jsonencode(settings, PrettyPrint=true));
                topLevelFields = string(fieldnames(settings));
                for fieldIndex = 1:numel(topLevelFields)
                    fieldName = topLevelFields(fieldIndex);
                    if startsWith(fieldName, 'x_')
                        restoredFieldName = '_' + extractAfter(fieldName, 'x_');
                        jsonField = string(newline) + string('  ') + char(34) + fieldName + char(34) + string(':');
                        restoredJsonField = string(newline) + string('  ') + char(34) + restoredFieldName + char(34) + string(':');
                        settingsJson = replace(settingsJson, jsonField, restoredJsonField);
                    end
                end
                writelines(settingsJson, settingsPath);
            catch err
                app.StatusLabel.Text = 'API key save failed.';
                uialert(app.UIFigure, err.message, 'API key save failed');
                return;
            end
            app.ApiKeyEditField.Value = '';
            if strlength(string(app.SettingsPathOverride)) > 0
                app.StatusLabel.Text = 'API key saved to the isolated test settings file.';
            else
                app.StatusLabel.Text = 'API key saved to config/settings.json. An environment variable still takes precedence.';
            end
        end

        function saveCandidateReview(app)
            % Write back only the reviewer-editable columns so every other CSV
            % column (works_count included) and the row order stay untouched.
            candidateTable = AnyResearchApp.readCandidateCsv(app.BatchCandidatePath);
            reviewed = app.CandidateTable.Data;
            if size(reviewed, 1) ~= height(candidateTable)
                error('AnyResearch:candidateRows', ...
                    'The review table has %d rows but the candidate CSV has %d.', ...
                    size(reviewed, 1), height(candidateTable));
            end
            displayedNames = string(app.CandidateTable.ColumnName(:))';
            for name = ["include", "role", "note"]
                column = find(displayedNames == name, 1);
                if isempty(column); continue; end
                values = reviewed(:, column);
                if name == "include"
                    candidateTable.include = cellfun(@AnyResearchApp.includeValue, values);
                else
                    texts = strings(numel(values), 1);
                    for row = 1:numel(values)
                        texts(row) = string(values{row});
                    end
                    candidateTable.(name) = texts;
                end
            end
            writetable(candidateTable, app.BatchCandidatePath);
        end

        function showBatchStep(app, step)
            step = max(1, min(4, step));
            heights = {0, 0, 0, 0}; heights{step} = '1x';
            app.BatchContentGrid.RowHeight = heights;
            app.BatchGeneratePanel.Visible = step == 1; app.BatchReviewPanel.Visible = step == 2;
            app.BatchPromotePanel.Visible = step == 3; app.BatchRunPanel.Visible = step == 4;
            app.BatchBackButton.Enable = matlab.lang.OnOffSwitchState(step > 1);
            app.BatchStep = step;
        end

    end

    methods (Access = public)

        function options = getBatchRunOptionsForTesting(app)
            options = app.getBatchRunOptions();
        end

        function onBatchRunCompleteForTesting(app, result, err)
            app.onBatchRunComplete(result, err);
        end

        function loadCandidateReviewForTesting(app)
            app.loadCandidateReview();
        end

        function options = getBatchPrepareOptionsForTesting(app)
            options = app.getBatchPrepareOptions();
        end

        function searchOptions = getSearchOptionsForTesting(app)
            searchOptions = app.getSearchOptions();
        end

        function promoteCandidatesForTesting(app)
            app.PromoteCandidatesButtonPushed([]);
        end

        function reviewCandidatesForTesting(app)
            app.ReviewCandidatesButtonPushed([]);
        end

        function runBatchForTesting(app)
            app.RunBatchButtonPushed([]);
        end

        function runSearchForTesting(app)
            app.SearchRunButtonPushed([]);
        end

        function saveApiKeyForTesting(app)
            app.SaveApiKeyButtonPushed([]);
        end

        function setBatchPathsForTesting(app, candidatePath, reviewedPath)
            arguments
                app
                candidatePath (1,1) string
                reviewedPath (1,1) string
            end
            app.BatchCandidatePath = candidatePath;
            app.ReviewedInstitutionsPath = reviewedPath;
        end

        function setBatchStepForTesting(app, step)
            arguments
                app
                step (1,1) double
            end
            app.showBatchStep(step);
        end

        function setSettingsPathForTesting(app, settingsPath)
            arguments
                app
                settingsPath (1,1) string
            end
            app.SettingsPathOverride = settingsPath;
        end

    end

    methods (Static)

        function assertDateRange(fromDate, toDate)
            % An unset (NaT) side leaves that end of the range open.
            if ~isnat(fromDate) && ~isnat(toDate) && fromDate > toDate
                error('AnyResearch:invalidDateRange', 'From date must not be later than To date.');
            end
        end

        function candidateTable = readCandidateCsv(csvPath)
            % Text columns are read as string with blanks as "" (an all-blank
            % column such as note would otherwise become NaN doubles and be
            % written back as "NaN"); only include and works_count are numeric.
            importOptions = detectImportOptions(csvPath, TextType="string");
            textVariables = setdiff(string(importOptions.VariableNames), ["include", "works_count"]);
            importOptions = setvartype(importOptions, cellstr(textVariables), "string");
            importOptions = setvaropts(importOptions, cellstr(textVariables), FillValue="");
            candidateTable = readtable(csvPath, importOptions);
        end

        function value = includeValue(cellValue)
            % Review-table include cell -> 1/0 (blank or unparsable means skip).
            if isnumeric(cellValue) || islogical(cellValue)
                value = double(cellValue);
            else
                value = str2double(string(cellValue));
            end
            if isempty(value) || isnan(value)
                value = 0;
            end
        end

        function projectRoot = resolveProjectRoot()
            % mfilename('fullpath') does not resolve correctly when this
            % class is loaded from the packaged AnyResearchApp.mlapp (it
            % resolves to a path outside the repository), so project-root
            % lookups must go through which() on the class name instead,
            % which resolves correctly for both the plain-text src/app/
            % source and the packaged .mlapp.
            appFilePath = which('AnyResearchApp');
            if isempty(appFilePath)
                appFilePath = mfilename('fullpath');
            end
            if endsWith(lower(appFilePath), '.mlapp')
                projectRoot = fileparts(appFilePath);
            else
                projectRoot = fileparts(fileparts(fileparts(appFilePath)));
            end
        end

        function candidatePath = prepareInstitutions(options)
            projectRoot = AnyResearchApp.resolveProjectRoot();
            addpath(fullfile(projectRoot, "src", "openalex"));
            addpath(fullfile(projectRoot, "src", "config"));
            addpath(fullfile(projectRoot, "src", "util"));
            candidatePath = prepare_institutions_csv(options.targetNames, ...
                outputPath=options.candidatePath, countryFilter=options.countryFilter, ...
                maxCandidates=3, mergeWith=options.reviewedPath);
        end

        function result = runBatchPipeline(options)
            projectRoot = AnyResearchApp.resolveProjectRoot();
            addpath(fullfile(projectRoot, "src", "pipeline"));
            result = run_batch_from_institutions_list(options.institutionsCsv, options.query, options.fromDate, options.toDate, ...
                batchRootDir=options.batchRootDir, dryRun=options.dryRun);
        end

        function result = runSearchPipeline(searchOptions)
            projectRoot = AnyResearchApp.resolveProjectRoot();
            addpath(fullfile(projectRoot, "src", "pipeline"));
            result = run_pipeline(searchOptions.query, searchOptions.fromDate, searchOptions.toDate, ...
                language=searchOptions.language, requireOpenAccess=searchOptions.requireOpenAccess, ...
                requireAbstract=searchOptions.requireAbstract, filterCountryCode=searchOptions.filterCountryCode, ...
                sortBy=searchOptions.sortBy, filterType=searchOptions.filterType, ...
                citedByMin=searchOptions.citedByMin, citedByMax=searchOptions.citedByMax, ...
                seedId=searchOptions.seedId, snowballMode=searchOptions.snowballMode, topN=searchOptions.topN, ...
                firstAuthorInstitution=searchOptions.firstAuthorInstitution, ...
                firstAuthorInstitutionId=searchOptions.firstAuthorInstitutionId, ...
                enablePdfDownload=searchOptions.enablePdfDownload, pdfMaxRows=searchOptions.pdfMaxRows, ...
                enablePdfTextExtraction=searchOptions.enablePdfTextExtraction, ...
                enableKeywordEvidence=searchOptions.enableKeywordEvidence);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FromDatePicker.Value = datetime(2026, 1, 1);
            app.ToDatePicker.Value = datetime(2026, 7, 18);
            app.BatchFromDatePicker.Value = datetime(2025, 1, 1);
            app.BatchToDatePicker.Value = datetime(2025, 12, 31);
            projectRoot = AnyResearchApp.resolveProjectRoot();
            app.BatchCandidatePath = string(fullfile(projectRoot, 'data', 'list', 'institutions_candidate.csv'));
            app.ReviewedInstitutionsPath = string(fullfile(projectRoot, 'data', 'list', 'institutions.csv'));
            app.showBatchStep(1);
            app.CandidateTable.ColumnEditable = logical([0 0 0 0 1 1 1 0]);
            app.associateFieldLabels();
        end

        % Close request function: UIFigure
        function CloseRequestFcn(app, event)
            delete(app.UIFigure);
        end

        % Button pushed function: SearchRunButton
        function SearchRunButtonPushed(app, event)
            if ~app.hasSearchQuery()
                uialert(app.UIFigure, 'Enter a search query or a seed DOI / OpenAlex work ID before running.', 'Query required');
                return;
            end
            app.SearchRunButton.Enable = 'off';
            app.StatusLabel.Text = 'Search is running...';
            drawnow;
            try
                result = AnyResearchApp.runSearchPipeline(app.getSearchOptions());
                app.onSearchComplete(result, []);
            catch err
                app.onSearchComplete([], err);
            end
            app.SearchRunButton.Enable = 'on';
        end

        % Button pushed function: GenerateCandidatesButton
        function GenerateCandidatesButtonPushed(app, event)
            app.GenerateCandidatesButton.Enable = 'off';
            app.StatusLabel.Text = 'Generating institution candidates...';
            drawnow;
            try
                result = AnyResearchApp.prepareInstitutions(app.getBatchPrepareOptions());
                app.onPrepareInstitutionsComplete(result, []);
            catch err
                app.onPrepareInstitutionsComplete([], err);
            end
            app.GenerateCandidatesButton.Enable = 'on';
        end

        % Button pushed function: ReviewCandidatesButton
        function ReviewCandidatesButtonPushed(app, event)
            if ~isfile(app.BatchCandidatePath)
                uialert(app.UIFigure, 'Generate institution candidates before reviewing.', 'Candidates required');
                return;
            end
            app.saveCandidateReview();
            app.showBatchStep(3);
        end

        % Button pushed function: PromoteCandidatesButton
        function PromoteCandidatesButtonPushed(app, event)
            if ~isfile(app.BatchCandidatePath)
                uialert(app.UIFigure, 'Generate institution candidates before promoting.', 'Candidates required');
                return;
            end
            app.saveCandidateReview();
            promote_reviewed_institutions_csv(app.BatchCandidatePath, app.ReviewedInstitutionsPath);
            app.StatusLabel.Text = 'Reviewed institutions promoted.';
            app.showBatchStep(4);
        end

        % Button pushed function: RunBatchButton
        function RunBatchButtonPushed(app, event)
            if ~isfile(app.ReviewedInstitutionsPath)
                uialert(app.UIFigure, 'Promote reviewed candidates before running the batch.', 'Institutions required');
                return;
            end
            app.RunBatchButton.Enable = 'off';
            app.StatusLabel.Text = 'Batch is running...';
            drawnow;
            try
                result = AnyResearchApp.runBatchPipeline(app.getBatchRunOptions());
                app.onBatchRunComplete(result, []);
            catch err
                app.onBatchRunComplete([], err);
            end
            app.RunBatchButton.Enable = 'on';
        end

        % Button pushed function: BatchBackButton
        function BatchBackButtonPushed(app, event)
            app.showBatchStep(max(1, app.BatchStep - 1));
        end

        % Button pushed function: SaveApiKeyButton
        function SaveApiKeyButtonPushed(app, event)
            app.saveApiKey();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'AnyResearch';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequestFcn, true);
            app.RootGrid = uigridlayout(app.UIFigure);
            app.AppTitleLabel = uilabel(app.RootGrid);
            app.AppTitleLabel.Text = 'AnyResearch';
            app.MainTabGroup = uitabgroup(app.RootGrid);
            app.SearchTab = uitab(app.MainTabGroup);
            app.SearchTab.Title = 'Search';
            app.SearchGrid = uigridlayout(app.SearchTab);
            app.SearchParametersPanel = uipanel(app.SearchGrid);
            app.SearchParametersPanel.Title = 'Layer 0 search parameters';
            app.SearchParametersGrid = uigridlayout(app.SearchParametersPanel);
            app.BasicPanel = uipanel(app.SearchParametersGrid);
            app.BasicPanel.Title = 'Basic';
            app.BasicGrid = uigridlayout(app.BasicPanel);
            app.QueryLabel = uilabel(app.BasicGrid);
            app.QueryLabel.Text = 'Query';
            app.QueryEditField = uieditfield(app.BasicGrid, 'text');
            app.QueryEditField.Placeholder = 'e.g. machine learning AND (radar | lidar)';
            app.FromDateLabel = uilabel(app.BasicGrid);
            app.FromDateLabel.Text = 'From date';
            app.ToDateLabel = uilabel(app.BasicGrid);
            app.ToDateLabel.Text = 'To date';
            app.FromDatePicker = uidatepicker(app.BasicGrid);
            app.FromDatePicker.DisplayFormat = 'yyyy-MM-dd';
            app.ToDatePicker = uidatepicker(app.BasicGrid);
            app.ToDatePicker.DisplayFormat = 'yyyy-MM-dd';
            app.SortByLabel = uilabel(app.BasicGrid);
            app.SortByLabel.Text = 'Sort order';
            app.SortByDropDown = uidropdown(app.BasicGrid);
            app.SortByDropDown.Items = {'cited_by_count:desc', 'publication_date:desc', 'relevance_score'};
            app.SortByDropDown.Value = 'cited_by_count:desc';
            app.FiltersPanel = uipanel(app.SearchParametersGrid);
            app.FiltersPanel.Title = 'Filters';
            app.FiltersGrid = uigridlayout(app.FiltersPanel);
            app.FilterTypeLabel = uilabel(app.FiltersGrid);
            app.FilterTypeLabel.Text = 'Document type filter';
            app.FilterTypeEditField = uieditfield(app.FiltersGrid, 'text');
            app.FilterTypeEditField.Tooltip = 'Enter document types separated by commas, or leave blank to include all types.';
            app.FilterTypeEditField.Placeholder = 'article, review, or blank for all';
            app.CitedByMinLabel = uilabel(app.FiltersGrid);
            app.CitedByMinLabel.Text = 'Minimum citations';
            app.CitedByMaxLabel = uilabel(app.FiltersGrid);
            app.CitedByMaxLabel.Text = 'Maximum citations (0 = off)';
            app.CitedByMinField = uieditfield(app.FiltersGrid, 'numeric');
            app.CitedByMinField.Limits = [0 Inf];
            app.CitedByMaxField = uieditfield(app.FiltersGrid, 'numeric');
            app.CitedByMaxField.Limits = [0 Inf];
            app.CitedByMaxField.Tooltip = 'Set to 0 for no maximum citation limit.';
            app.LanguageLabel = uilabel(app.FiltersGrid);
            app.LanguageLabel.Text = 'Language';
            app.CountryCodeLabel = uilabel(app.FiltersGrid);
            app.CountryCodeLabel.Text = 'Country code';
            app.LanguageEditField = uieditfield(app.FiltersGrid, 'text');
            app.LanguageEditField.Value = 'en';
            app.CountryCodeEditField = uieditfield(app.FiltersGrid, 'text');
            app.CountryCodeEditField.Value = 'JP';
            app.RequireOpenAccessCheckBox = uicheckbox(app.FiltersGrid);
            app.RequireOpenAccessCheckBox.Text = 'Require Open Access';
            app.RequireOpenAccessCheckBox.Value = true;
            app.RequireAbstractCheckBox = uicheckbox(app.FiltersGrid);
            app.RequireAbstractCheckBox.Text = 'Require abstract';
            app.RequireAbstractCheckBox.Value = true;
            app.AdvancedPanel = uipanel(app.SearchParametersGrid);
            app.AdvancedPanel.Title = 'Advanced';
            app.AdvancedGrid = uigridlayout(app.AdvancedPanel);
            app.SeedIdLabel = uilabel(app.AdvancedGrid);
            app.SeedIdLabel.Text = 'Seed DOI or OpenAlex work ID';
            app.SeedIdEditField = uieditfield(app.AdvancedGrid, 'text');
            app.SeedIdEditField.Tooltip = 'Enter a DOI or OpenAlex work ID to use as the starting point for snowball retrieval.';
            app.SeedIdEditField.Placeholder = '10.1234/example or W2741809807';
            app.SnowballModeLabel = uilabel(app.AdvancedGrid);
            app.SnowballModeLabel.Text = 'Snowball mode';
            app.SnowballModeDropDown = uidropdown(app.AdvancedGrid);
            app.SnowballModeDropDown.Items = {'citing', 'referenced'};
            app.SnowballModeDropDown.Tooltip = 'citing finds works that cite this work. referenced finds works cited by this work.';
            app.SnowballModeDropDown.Value = 'citing';
            app.TopNLabel = uilabel(app.AdvancedGrid);
            app.TopNLabel.Text = 'Top N in Summary';
            app.TopNField = uieditfield(app.AdvancedGrid, 'numeric');
            app.TopNField.Limits = [1 Inf];
            app.TopNField.RoundFractionalValues = 'on';
            app.TopNField.Value = 10;
            app.InstitutionLabel = uilabel(app.AdvancedGrid);
            app.InstitutionLabel.Text = 'First-author institution';
            app.InstitutionEditField = uieditfield(app.AdvancedGrid, 'text');
            app.InstitutionEditField.Placeholder = 'e.g. Nagoya University';
            app.InstitutionIdLabel = uilabel(app.AdvancedGrid);
            app.InstitutionIdLabel.Text = 'Institution ID';
            app.InstitutionIdEditField = uieditfield(app.AdvancedGrid, 'text');
            app.InstitutionIdEditField.Placeholder = 'I26973366';
            app.SearchRunButton = uibutton(app.SearchGrid, 'push');
            app.SearchRunButton.ButtonPushedFcn = createCallbackFcn(app, @SearchRunButtonPushed, true);
            app.SearchRunButton.Text = 'Run Search';
            app.BatchTab = uitab(app.MainTabGroup);
            app.BatchTab.Title = 'Batch';
            app.BatchGrid = uigridlayout(app.BatchTab);
            app.BatchStepLabel = uilabel(app.BatchGrid);
            app.BatchStepLabel.Text = '1. Generate candidates  →  2. Review  →  3. Promote  →  4. Run batch';
            app.BatchContentGrid = uigridlayout(app.BatchGrid);
            app.BatchGeneratePanel = uipanel(app.BatchContentGrid);
            app.BatchGeneratePanel.Title = 'Step 1 — Generate institution candidates';
            app.BatchGenerateGrid = uigridlayout(app.BatchGeneratePanel);
            app.TargetNamesLabel = uilabel(app.BatchGenerateGrid);
            app.TargetNamesLabel.Text = 'Target institution names';
            app.TargetNamesTextArea = uitextarea(app.BatchGenerateGrid);
            app.TargetNamesTextArea.Tooltip = 'Enter one institution name per line.';
            app.TargetNamesTextArea.Placeholder = 'One institution name per line, e.g. Nagoya University';
            app.PrepareCountryLabel = uilabel(app.BatchGenerateGrid);
            app.PrepareCountryLabel.Text = 'Country filter';
            app.PrepareCountryEditField = uieditfield(app.BatchGenerateGrid, 'text');
            app.PrepareCountryEditField.Tooltip = 'Filter candidate institution matches by country code. Leave blank to include all countries.';
            app.PrepareCountryEditField.Value = 'JP';
            app.GenerateCandidatesButton = uibutton(app.BatchGenerateGrid, 'push');
            app.GenerateCandidatesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCandidatesButtonPushed, true);
            app.GenerateCandidatesButton.Text = 'Generate candidates';
            app.BatchReviewPanel = uipanel(app.BatchContentGrid);
            app.BatchReviewPanel.Title = 'Step 2 — Review candidates';
            app.BatchReviewPanel.Visible = 'off';
            app.BatchReviewGrid = uigridlayout(app.BatchReviewPanel);
            app.CandidateTable = uitable(app.BatchReviewGrid);
            app.CandidateTable.ColumnName = {'account'; 'openalex_institution_id'; 'display_name'; 'country_code'; 'include'; 'role'; 'note'; 'status'};
            app.ReviewCandidatesButton = uibutton(app.BatchReviewGrid, 'push');
            app.ReviewCandidatesButton.ButtonPushedFcn = createCallbackFcn(app, @ReviewCandidatesButtonPushed, true);
            app.ReviewCandidatesButton.Text = 'Save review and continue';
            app.BatchPromotePanel = uipanel(app.BatchContentGrid);
            app.BatchPromotePanel.Title = 'Step 3 — Promote reviewed candidates';
            app.BatchPromotePanel.Visible = 'off';
            app.BatchPromoteGrid = uigridlayout(app.BatchPromotePanel);
            app.PromoteHelpLabel = uilabel(app.BatchPromoteGrid);
            app.PromoteHelpLabel.Text = 'Promote rows marked include=true to the reviewed institutions list used by batch runs.';
            app.PromoteCandidatesButton = uibutton(app.BatchPromoteGrid, 'push');
            app.PromoteCandidatesButton.ButtonPushedFcn = createCallbackFcn(app, @PromoteCandidatesButtonPushed, true);
            app.PromoteCandidatesButton.Text = 'Promote reviewed candidates';
            app.BatchRunPanel = uipanel(app.BatchContentGrid);
            app.BatchRunPanel.Title = 'Step 4 — Run batch';
            app.BatchRunPanel.Visible = 'off';
            app.BatchRunGrid = uigridlayout(app.BatchRunPanel);
            app.BatchQueryLabel = uilabel(app.BatchRunGrid);
            app.BatchQueryLabel.Text = 'Query';
            app.BatchQueryEditField = uieditfield(app.BatchRunGrid, 'text');
            app.BatchQueryEditField.Placeholder = 'e.g. machine learning AND (radar | lidar)';
            app.BatchFromDateLabel = uilabel(app.BatchRunGrid);
            app.BatchFromDateLabel.Text = 'From date';
            app.BatchToDateLabel = uilabel(app.BatchRunGrid);
            app.BatchToDateLabel.Text = 'To date';
            app.BatchFromDatePicker = uidatepicker(app.BatchRunGrid);
            app.BatchFromDatePicker.DisplayFormat = 'yyyy-MM-dd';
            app.BatchToDatePicker = uidatepicker(app.BatchRunGrid);
            app.BatchToDatePicker.DisplayFormat = 'yyyy-MM-dd';
            app.BatchDryRunCheckBox = uicheckbox(app.BatchRunGrid);
            app.BatchDryRunCheckBox.Tooltip = 'Queries OpenAlex for each institution filter and result count, then writes the batch summary. It does not fetch works or create per-institution or merged result exports.';
            app.BatchDryRunCheckBox.Text = 'Dry run (preview filters and counts only)';
            app.RunBatchButton = uibutton(app.BatchRunGrid, 'push');
            app.RunBatchButton.ButtonPushedFcn = createCallbackFcn(app, @RunBatchButtonPushed, true);
            app.RunBatchButton.Text = 'Run batch';
            app.BatchBackButton = uibutton(app.BatchGrid, 'push');
            app.BatchBackButton.ButtonPushedFcn = createCallbackFcn(app, @BatchBackButtonPushed, true);
            app.BatchBackButton.Enable = 'off';
            app.BatchBackButton.Text = 'Back';
            app.AnalyticsPdfTab = uitab(app.MainTabGroup);
            app.AnalyticsPdfTab.Title = 'Analytics & PDF';
            app.AnalyticsPdfGrid = uigridlayout(app.AnalyticsPdfTab);
            app.AnalyticsPanel = uipanel(app.AnalyticsPdfGrid);
            app.AnalyticsPanel.Title = 'Layer 2 — Analytics';
            app.AnalyticsStatusLabel = uilabel(app.AnalyticsPanel);
            app.AnalyticsStatusLabel.Text = 'Analytics are automatically computed and included in Layer 0 and Layer 1 results.';
            app.PdfPanel = uipanel(app.AnalyticsPdfGrid);
            app.PdfPanel.Title = 'Layer 3 — PDF options';
            app.PdfOptionsGrid = uigridlayout(app.PdfPanel);
            app.EnablePdfDownloadCheckBox = uicheckbox(app.PdfOptionsGrid);
            app.EnablePdfDownloadCheckBox.Tooltip = 'Checks up to the specified number of OpenAlex result rows for an Open Access PDF and downloads it when available. This option is off by default.';
            app.EnablePdfDownloadCheckBox.Text = 'Download Open Access PDFs';
            app.PdfMaxRowsLabel = uilabel(app.PdfOptionsGrid);
            app.PdfMaxRowsLabel.Text = 'PDF check limit (first N results)';
            app.PdfMaxRowsField = uieditfield(app.PdfOptionsGrid, 'numeric');
            app.PdfMaxRowsField.Limits = [1 Inf];
            app.PdfMaxRowsField.RoundFractionalValues = 'on';
            app.PdfMaxRowsField.Tooltip = 'Limits PDF download processing to the first N rows of the OpenAlex results.';
            app.PdfMaxRowsField.Value = 20;
            app.EnablePdfTextCheckBox = uicheckbox(app.PdfOptionsGrid);
            app.EnablePdfTextCheckBox.Tooltip = 'Extracts text from downloaded PDFs. It does not run when PDF download is off.';
            app.EnablePdfTextCheckBox.Text = 'Extract PDF text';
            app.EnablePdfTextCheckBox.Value = true;
            app.EnableKeywordEvidenceCheckBox = uicheckbox(app.PdfOptionsGrid);
            app.EnableKeywordEvidenceCheckBox.Tooltip = 'Extracts keyword evidence from downloaded PDF text. It does not run when PDF download is off.';
            app.EnableKeywordEvidenceCheckBox.Text = 'Extract keyword evidence';
            app.EnableKeywordEvidenceCheckBox.Value = true;
            app.SettingsTab = uitab(app.MainTabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsGrid = uigridlayout(app.SettingsTab);
            app.ApiKeyLabel = uilabel(app.SettingsGrid);
            app.ApiKeyLabel.Text = 'OpenAlex API key';
            app.ApiKeyEditField = uieditfield(app.SettingsGrid, 'text');
            app.ApiKeyEditField.Tooltip = 'Saves the entered key to config/settings.json. The field is cleared after saving.';
            app.ApiKeyEditField.Placeholder = 'Enter a new key to save it in config/settings.json';
            app.SaveApiKeyButton = uibutton(app.SettingsGrid, 'push');
            app.SaveApiKeyButton.ButtonPushedFcn = createCallbackFcn(app, @SaveApiKeyButtonPushed, true);
            app.SaveApiKeyButton.Tooltip = 'Saves the entered API key. If ANYRESEARCH_OPENALEX_API_KEY is set, that environment variable continues to take precedence.';
            app.SaveApiKeyButton.Text = 'Save API key';
            app.SettingsHelpLabel = uilabel(app.SettingsGrid);
            app.SettingsHelpLabel.Text = 'Environment variable ANYRESEARCH_OPENALEX_API_KEY takes precedence over config/settings.json. The existing configuration mechanism is otherwise unchanged.';
            app.StatusLabel = uilabel(app.RootGrid);
            app.StatusLabel.Text = 'Ready.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnyResearchApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end